classdef Convolutionwindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure               % Convolution
        LabelDropDown     matlab.ui.control.Label        % Function1
        DropDown          matlab.ui.control.DropDown     % Rect, Sinc, Trig
        fn1axis           matlab.ui.control.UIAxes       % Function 1
        LabelKnob         matlab.ui.control.Label        % Amplitude
        ampKnop1          matlab.ui.control.Knob         % [0 10]
        fn2axis           matlab.ui.control.UIAxes       % Function 2
        Label             matlab.ui.control.Label        % Drop Down
        DropDown2         matlab.ui.control.DropDown     % Rect, Sinc, Trig
        Label2            matlab.ui.control.Label        % Amplitude
        ampKnop2          matlab.ui.control.Knob         % [0 10]
        convaxis          matlab.ui.control.UIAxes       % Title
        animatedconvaxis  matlab.ui.control.UIAxes       % Title
        Label3            matlab.ui.control.Label        % Width
        widthKnop1        matlab.ui.control.Knob         % [1 10]
        Label4            matlab.ui.control.Label        % Width
        widthKnop2        matlab.ui.control.Knob         % [1 10]
        Button            matlab.ui.control.Button       % Back
        LabelToggleSwitch matlab.ui.control.Label        % Scale
        scaleswitch1      matlab.ui.control.ToggleSwitch % Log, Linear
        Label5            matlab.ui.control.Label        % Scale
        ToggleSwitch      matlab.ui.control.ToggleSwitch % Log, Linear
        Label6            matlab.ui.control.Label        % Scale
        ToggleSwitch2     matlab.ui.control.ToggleSwitch % Log, Linear
        Label7            matlab.ui.control.Label        % Scale
        ToggleSwitch3     matlab.ui.control.ToggleSwitch % Log, Linear
    end


    properties (Access = public)
        %global variables
        function1;
        function2;
        %time axes
        t1;
        t2;
        %convolution time axis
        tc;
        
        xlimit;
    end

    methods (Access = private)
          % function to animate convolution ( second graph)
        function  convanimation(app)    
            cla(app.animatedconvaxis)
           if isvector(app.function1) && isvector(app.function2); % error handling
                axis_color= [0.5 0.5 0.5];
               convolution = conv(app.function1,app.function2)/100; % convolution
              tf = fliplr(-app.t2);
              % slide range of 'fn2' to discard non-ovelapping areas with 'fn1' in the convolution
              tf = tf + ( min(app.t1)-max(tf) );
              % get the range of the convolution
              app.tc = [ tf app.t1(2:end)];
              app.tc = app.tc+max(app.t2);
             r = plot(app.animatedconvaxis,app.tc, convolution);
              app.xlimit = [ ( min(app.t1)-abs(max(tf)-min(tf)) - 1 ) ( max(app.t1)+abs(max(tf)-min(tf)) + 1 ) ];
              xlim(app.animatedconvaxis,app.xlimit  );
              title(app.animatedconvaxis,'Convolutional Product (Animated)', 'Color', axis_color );
              u_ym = 10;  
              % animation block
              for i=1:100:length(app.tc)
                
                % control speed of animation minimum is 0, the lower the faster
                  pause(0);
                  drawnow;
           
                  
                  tf=tf+0.01;

                % show overlapping regions
               
                  sx = min( max( tf(1), min(app.t1) ), max(app.t1) );  
                 
                
                % show a second vetical line for the right boundary of overlapping region
                  ex = min( tf(end), max(app.t1) );  
                  ex_a = [ex ex];


                % update the plot of convolutional product 'c', its handle is r
                  set(r,'EraseMode','xor');
                  set(r,'XData',app.tc(1:i),'YData',convolution(1:i) );
                
              end;


           end
        end


        function  conv(app) % normal convolution
            
           if isvector(app.function1) & isvector(app.function2); % error handling
           convolution = conv(app.function1,app.function2)/100;
           plot(app.convaxis,app.tc,convolution);
           xlim(app.convaxis, app.xlimit );
           title(app.convaxis,'Convolutional Product', 'Color', [0.5 0.5 0.5] );
                
           end
        end
     
        function updatefn1Plot(app) % update plots function for the first selected function
           % getting parameters from GUI
            value = app.DropDown.Value;
            A = app.ampKnop1.Value;
            W = floor(app.widthKnop1.Value);
    switch(value)
        case 'Rect'
            [app.function1,app.t1] = rect(100,A,W,10);
        case 'Sinc'
            [app.function1,app.t1] = sincc(100,A,W,10); 
        case 'Trig'
            [app.function1,app.t1] = trig(100,A,W,10);     
    end
   plot(app.fn1axis,app.t1,app.function1);
       convanimation(app)
      conv(app);
        end
      function updatefn2Plot(app) % update plots function for the second selected function
            value = app.DropDown2.Value;
            A = app.ampKnop2.Value;
            W = floor(app.widthKnop2.Value);
    switch(value)
        case 'Rect'
            [app.function2,app.t2] = rect(100,A,W,10);
        case 'Sinc'
            [app.function2,app.t2] = sincc(100,A,W,10); 
        case 'Trig'
            [app.function2,app.t2] = trig(100,A,W,10);     
    end
   plot(app.fn2axis,app.t2,app.function2);
   convanimation(app)   
   conv(app);   
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % DropDown value changed function
        function function1Change(app, event)
           updatefn1Plot(app)
        end

        % DropDown2 value changed function
        function function2Change(app, event)
           updatefn2Plot(app)
            
        end

        % ampKnop1 value changed function
        function Amp1Changed(app)
           updatefn1Plot(app)

        end

        % widthKnop1 value changed function
        function Widthknop1change(app)
                updatefn1Plot(app)

            
        end

        % ampKnop2 value changed function
        function amp2change(app)

            updatefn2Plot(app);
        end

        % widthKnop2 value changed function
        function widthKnop2change(app)

            updatefn2Plot(app);
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            Gate;
            close(app.UIFigure);
        end

        % ToggleSwitch3 value changed function
        function ToggleSwitch3ValueChanged(app)
            value = app.ToggleSwitch3.Value;
            if strcmp(value, 'Linear')
            set(app.fn1axis,'yscale','linear');
            else
            set(app.fn1axis,'yscale','log');
            end            
        end

        % ToggleSwitch2 value changed function
        function ToggleSwitch2ValueChanged(app)
            value = app.ToggleSwitch2.Value;
            if strcmp(value, 'Linear')
            set(app.fn2axis,'yscale','linear');
            else
            set(app.fn2axis,'yscale','log');
            end                 
        end

        % ToggleSwitch value changed function
        function ToggleSwitchValueChanged(app)
            value = app.ToggleSwitch.Value;
            if strcmp(value, 'Linear')
            set(app.convaxis,'yscale','linear');
            else
            set(app.convaxis,'yscale','log');
            end                 
        end

        % scaleswitch1 value changed function
        function scaleswitch1ValueChanged(app)
            value = app.scaleswitch1.Value;
            if strcmp(value, 'Linear')
            set(app.animatedconvaxis,'yscale','linear');
            else
            set(app.animatedconvaxis,'yscale','log');
            end                 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Convolution';
            setAutoResize(app, app.UIFigure, true)
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Function1';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Rect', 'Sinc', 'Trig'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @function1Change, true);
            app.DropDown.Value = 'Rect';
            app.fn1axis = uiaxes(app.UIFigure);
            title(app.fn1axis, 'Function 1');
            xlabel(app.fn1axis, 't');
            ylabel(app.fn1axis, 'Function1(t)');
            app.LabelKnob = uilabel(app.UIFigure);
            app.LabelKnob.Text = 'Amplitude';
            app.ampKnop1 = uiknob(app.UIFigure, 'continuous');
            app.ampKnop1.Limits = [0 10];
            app.ampKnop1.ValueChangedFcn = createCallbackFcn(app, @Amp1Changed);
            app.ampKnop1.Value = 1;
            app.fn2axis = uiaxes(app.UIFigure);
            title(app.fn2axis, 'Function 2');
            xlabel(app.fn2axis, 't');
            ylabel(app.fn2axis, 'Function2(t)');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Drop Down';
            app.DropDown2 = uidropdown(app.UIFigure);
            app.DropDown2.Items = {'Rect', 'Sinc', 'Trig'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @function2Change, true);
            app.DropDown2.Value = 'Rect';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Amplitude';
            app.ampKnop2 = uiknob(app.UIFigure, 'continuous');
            app.ampKnop2.Limits = [0 10];
            app.ampKnop2.ValueChangedFcn = createCallbackFcn(app, @amp2change);
            app.ampKnop2.Value = 1;
            app.convaxis = uiaxes(app.UIFigure);
            title(app.convaxis, 'Title');
            xlabel(app.convaxis, 'X');
            ylabel(app.convaxis, 'Y');
            app.animatedconvaxis = uiaxes(app.UIFigure);
            title(app.animatedconvaxis, 'Title');
            xlabel(app.animatedconvaxis, 'X');
            ylabel(app.animatedconvaxis, 'Y');
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Width';
            app.widthKnop1 = uiknob(app.UIFigure, 'continuous');
            app.widthKnop1.Limits = [1 10];
            app.widthKnop1.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.widthKnop1.ValueChangedFcn = createCallbackFcn(app, @Widthknop1change);
            app.widthKnop1.MinorTicks = [];
            app.widthKnop1.Value = 1;
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Width';
            app.widthKnop2 = uiknob(app.UIFigure, 'continuous');
            app.widthKnop2.Limits = [1 10];
            app.widthKnop2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.widthKnop2.ValueChangedFcn = createCallbackFcn(app, @widthKnop2change);
            app.widthKnop2.MinorTicks = [];
            app.widthKnop2.Value = 1;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Back';
            app.LabelToggleSwitch = uilabel(app.UIFigure);
            app.LabelToggleSwitch.Text = 'Scale';
            app.scaleswitch1 = uiswitch(app.UIFigure, 'toggle');
            app.scaleswitch1.Items = {'Log', 'Linear'};
            app.scaleswitch1.ValueChangedFcn = createCallbackFcn(app, @scaleswitch1ValueChanged);
            app.scaleswitch1.Value = 'Linear';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Scale';
            app.ToggleSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ToggleSwitch.Items = {'Log', 'Linear'};
            app.ToggleSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged);
            app.ToggleSwitch.Value = 'Linear';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Scale';
            app.ToggleSwitch2 = uiswitch(app.UIFigure, 'toggle');
            app.ToggleSwitch2.Items = {'Log', 'Linear'};
            app.ToggleSwitch2.ValueChangedFcn = createCallbackFcn(app, @ToggleSwitch2ValueChanged);
            app.ToggleSwitch2.Value = 'Linear';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'Scale';
            app.ToggleSwitch3 = uiswitch(app.UIFigure, 'toggle');
            app.ToggleSwitch3.Items = {'Log', 'Linear'};
            app.ToggleSwitch3.ValueChangedFcn = createCallbackFcn(app, @ToggleSwitch3ValueChanged);
            app.ToggleSwitch3.Value = 'Linear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Convolutionwindow()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end