classdef Spectrumanalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % Spectru...
        TabGroup               matlab.ui.container.TabGroup       % Spectru...
        Tab                    matlab.ui.container.Tab            % Spectru...
        Panel                  matlab.ui.container.Panel          % Input S...
        DropDown               matlab.ui.control.DropDown         % Audio 1...
        Panel2                 matlab.ui.container.Panel          % DFT Set...
        DropDown2              matlab.ui.control.DropDown         % 8K-DFT,...
        UIAxes                 matlab.ui.control.UIAxes           % Time Do...
        UIAxes2                matlab.ui.control.UIAxes           % Frequen...
        NumericEditField       matlab.ui.control.NumericEditField % [-10000...
        Label                  matlab.ui.control.Label            % Frequen...
        Label2                 matlab.ui.control.Label            % Hz :
        NumericEditField2      matlab.ui.control.NumericEditField % [-99999...
        Label3                 matlab.ui.control.Label            % Hz
        Label4                 matlab.ui.control.Label            % RBW
        EditField              matlab.ui.control.EditField       
        Button                 matlab.ui.control.Button           % Back
        Panel5                 matlab.ui.container.Panel          % Calcula...
        Label6                 matlab.ui.control.Label            % Span Power
        power                  matlab.ui.control.EditField       
        Label7                 matlab.ui.control.Label            % RMS ave...
        RMSfield               matlab.ui.control.EditField       
        Label8                 matlab.ui.control.Label            % DC Bias
        DCBiasfield            matlab.ui.control.EditField       
        Label9                 matlab.ui.control.Label            % Peak
        Peakfield              matlab.ui.control.EditField       
        Label10                matlab.ui.control.Label            % Peak fr...
        Peakfrequencyfield     matlab.ui.control.EditField       
        Label11                matlab.ui.control.Label            % Fs
        Fsfield                matlab.ui.control.EditField       
        LabelToggleSwitch      matlab.ui.control.Label            % Scale
        scaleswitch1           matlab.ui.control.ToggleSwitch     % Log, Li...
        Label14                matlab.ui.control.Label            % There a...
        Label15                matlab.ui.control.Label            % -Spectr...
        Label16                matlab.ui.control.Label            % -Compar...
        Label17                matlab.ui.control.Label            % Input S...
        Tab2                   matlab.ui.container.Tab            % Compari...
        Panel3                 matlab.ui.container.Panel          % Window 1
        window1dropdown        matlab.ui.control.DropDown         % Rectang...
        LabelNumericEditField3 matlab.ui.control.Label            % Length
        NumericEditField3      matlab.ui.control.NumericEditField % [1 Inf]
        UIAxes3                matlab.ui.control.UIAxes           % Time Do...
        UIAxes4                matlab.ui.control.UIAxes           % Frequen...
        Panel4                 matlab.ui.container.Panel          % Window 2
        Label5                 matlab.ui.control.Label            % Length
        NumericEditField4      matlab.ui.control.NumericEditField % [1 Inf]
        window2dropdown        matlab.ui.control.DropDown         % Rectang...
        UIAxes5                matlab.ui.control.UIAxes           % Time Do...
        UIAxes6                matlab.ui.control.UIAxes           % Frequen...
        Label12                matlab.ui.control.Label            % Scale
        window2scaleswitch     matlab.ui.control.ToggleSwitch     % Log, Li...
        Label13                matlab.ui.control.Label            % Scale
        window1scaleswitch     matlab.ui.control.ToggleSwitch     % Log, Li...
    end


    properties (Access = public)
        %Global variables
        Signal; % Current Signal
        Signal_FFT; % Current Signal FFT
        N = 64000; % FFT order
        Fs;
    end

    methods (Access = public)
        function UpdateComparisonwindow1(app) % update graphs function for the first comparison window
        signal = app.Signal;
        value = app.window1dropdown.Value; % selected window
        switch(value)
            case 'No Window'
            app.NumericEditField3.Enable = 'off'; %disabling length entering option
            otherwise
               app.NumericEditField3.Enable = 'on';
               L = app.NumericEditField3.Value; % getting length
               if L>app.N % checking if the length is greated than the signal length ( error handling)
                   L = app.N; % if L > the signal length set the window to its maximum ( the signal length)
               end
               window = zeros(1,length(app.Signal)); % initiating window
               if(strcmp(value,'Rectangular'))
                   window(1:L) = 1; % creating rect window
                signal = app.Signal .* window; 
               elseif(strcmp(value,'Triangular'))
                 trigwin = triang(L); % creating trig window
                 trigwin = [ trigwin' zeros(1,length(signal)-L)]; % zero padding
                 signal = app.Signal .* trigwin;
               elseif(strcmp(value,'Hanning'))
                  hannwin = hann(L); % creating hanning window
                  hannwin = [ hannwin' zeros(1,length(signal)-L)]; % zero padding
                   signal = app.Signal .* hannwin;
               elseif(strcmp(value,'Hamming'))
                  hammwin = hamming(L); % creaing hamming window
                  hammwin = [ hammwin' zeros(1,length(signal)-L)]; % zero padding
                   signal = app.Signal .* hammwin;               
               
               end    
        end
       plot(app.UIAxes3,signal); % plotting time domain
       
        FFT = abs(fftshift(fft(signal,app.N))); % calculating FFT with desired order
              frequencyaxis = -length(FFT)/2:(length(FFT)/2)-1; %creating frequency axis
              frequencyaxis = frequencyaxis * app.Fs/app.N; % transforming it to real frequency
        plot(app.UIAxes4,frequencyaxis,FFT); % plotting frequency domain
        end
        
function UpdateComparisonwindow2(app) % same as Update Comparisonwindow1 but for comparisonwindow2
        signal = app.Signal;
        value = app.window2dropdown.Value;
        switch(value)
            case 'No Window'
            app.NumericEditField4.Enable = 'off';
            otherwise
               app.NumericEditField4.Enable = 'on';
               L = app.NumericEditField4.Value;
               if L>app.N
                   L = app.N;
               end
               window = zeros(1,length(app.Signal));
               if(strcmp(value,'Rectangular'))
                   window(1:L) = 1;
                signal = app.Signal .* window;
               elseif(strcmp(value,'Triangular'))
                 trigwin = triang(L);
                 trigwin = [ trigwin' zeros(1,length(signal)-L)];
                 signal = app.Signal .* trigwin;
               elseif(strcmp(value,'Hanning'))
                  hannwin = hann(L);
                  hannwin = [ hannwin' zeros(1,length(signal)-L)];
                   signal = app.Signal .* hannwin;
               elseif(strcmp(value,'Hamming'))
                  hammwin = hamming(L);
                  hammwin = [ hammwin' zeros(1,length(signal)-L)];
                   signal = app.Signal .* hammwin;               
               
               end    
        end
        plot(app.UIAxes5,signal); 
        FFT = abs(fftshift(fft(signal,app.N)));
        plot(app.UIAxes6,FFT);
        end


        function Updategraphs(app) % update graphs for the spectrum analyzer
            Value = app.DropDown.Value;
            switch(Value) % selecting input signal
                case 'Audio 1'
                    [app.Signal, app.Fs] = audioread('m.wav');
                    app.Signal = app.Signal';
                    totaltime = 1/app.Fs * length(app.Signal);
                    t = 0:1/app.Fs:totaltime-1/app.Fs; % creating time axis
                case 'Audio 2'
                    [app.Signal, app.Fs] = audioread('n.wav');
                    app.Signal = app.Signal';
                    totaltime = 1/app.Fs * length(app.Signal);
                    t = 0:1/app.Fs:totaltime-1/app.Fs; % creating time axis
 
                case 'Rect'
                    [app.Signal, t] = rect(100,1,1,app.N/100);
                    app.Fs = 100;
                case 'Sinc'
                    [app.Signal, t] = sincc(100,1,1,app.N/100);
                    app.Fs = 100;
                case 'Trig'
                    [app.Signal, t] = trig(100,1,1,app.N/100);
                    app.Fs = 100;
            end
          plot(app.UIAxes,t,app.Signal);  % ploting time domain
          
          %Frequency Domain
          value = app.DropDown2.Value;
          switch (value)
              case '8K-DFT'
                  app.N = 8000;
              case '16K-DFT'
                  app.N = 16000;
              case '32K-DFT'
                  app.N = 32000;
              case '64K-DFT'
                  app.N = 64000;
                                    
          end
              app.Signal_FFT = abs(fftshift(fft(app.Signal,app.N))); %calculating fft with desired order

              f1 = app.NumericEditField.Value; % getting frequency span limits
              f2 = app.NumericEditField2.Value; % getting frequency span limits
              frequencyaxis = -length(app.Signal_FFT)/2:(length(app.Signal_FFT)/2)-1; %creating frequency axis
              frequencyaxis = frequencyaxis * app.Fs/app.N; % transforming it to real frequency axis
              if f2>app.Fs/2
                  f2 = app.Fs/2;
                   app.NumericEditField.Value = f1;
                   app.NumericEditField2.Value = f2;
                  
              elseif f2<-app.Fs/2
                  f2 = -app.Fs/2;
                   app.NumericEditField.Value = f1;
                   app.NumericEditField2.Value = f2;

              end
              
              if f1<-app.Fs/2
                  f1 = -app.Fs/2;
                   app.NumericEditField.Value = f1;
                   app.NumericEditField2.Value = f2;

              elseif f1>app.Fs/2
                  f1 = app.Fs/2;
                   app.NumericEditField.Value = f1;
                   app.NumericEditField2.Value = f2;

              end
              if f1>f2
                  temp = f1;
                  f1 = f2;
                  f2 = temp;
                   app.NumericEditField.Value = f1;
                   app.NumericEditField2.Value = f2;
              end  
               if f1==f2
                   f1 = -app.Fs/2;
                   f2 = app.Fs/2;
                   app.NumericEditField.Value = f1;
                   app.NumericEditField2.Value = f2;
               end
              k = frequencyaxis>f1 & frequencyaxis<f2; % applying frequency span
              
              plot(app.UIAxes2,frequencyaxis(k),app.Signal_FFT(k));  %plotting Frequency domain

              app.EditField.Value = num2str(app.Fs/app.N); % calculating and outputting RBW
              app.Fsfield.Value = num2str(app.Fs); % sampling frequency
              app.DCBiasfield.Value = num2str(mean(app.Signal)); % DC Bias
              app.Peakfield.Value = num2str(max(app.Signal_FFT(k))); %peak finding
              app.Peakfrequencyfield.Value = num2str(((find(app.Signal_FFT == max(app.Signal_FFT(k))) - app.Fs/app.N ) * app.Fs/app.N) - app.Fs/2);
              app.RMSfield.Value = num2str(rms(app.Signal)); % RMS averaging
              if f1 >= 0 && f2 > 0 
              app.power.Value = num2str(bandpower(app.Signal, app.Fs, [f1 f2-1])/2);
              elseif f1<0  && f2<0
              app.power.Value = num2str(bandpower(app.Signal, app.Fs, [-f1 -f2-1])/2);
              elseif f1<0 && f2>0
                 positivePower = bandpower(app.Signal, app.Fs, [0 f2-1])/2;
                 negativePower = bandpower(app.Signal, app.Fs, [0 -f1-1])/2;
                 app.power.Value = num2str(positivePower+negativePower);
              end
     
        end


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%    set(app.UIFigure, 'Position', get(0, 'Screensize')); % starting in full screen
%       set(app.TabGroup, 'Position', get(0, 'Screensize'));
%  
        end

        % DropDown2 value changed function
        function DropDown2ValueChanged(app, event)
           Updategraphs(app);
                     
        end

        % DropDown value changed function
        function DropDownValueChanged(app, event)

            Updategraphs(app);
              app.NumericEditField.Value = -app.Fs/2;
              app.NumericEditField2.Value = app.Fs/2;
        end

        % NumericEditField value changed function
        function NumericEditFieldValueChanged(app)
   Updategraphs(app);            
        end

        % NumericEditField2 value changed function
        function NumericEditField2ValueChanged(app)
   Updategraphs(app);
        end

        % window1dropdown value changed function
        function window1dropdownValueChanged(app, event)
            if isvector(app.Signal) % checking if the user selected a signal ( error handling)
            UpdateComparisonwindow1(app);
            end
        
        end

        % NumericEditField3 value changed function
        function NumericEditField3ValueChanged(app)
            if isvector(app.Signal) % checking if the user selected a signal ( error handling)
            UpdateComparisonwindow1(app);
            end
        end

        % window2dropdown value changed function
        function window2dropdownValueChanged(app, event)
            if isvector(app.Signal) % checking if the user selected a signal ( error handling)
            UpdateComparisonwindow2(app);
            end
        end

        % NumericEditField4 value changed function
        function NumericEditField4ValueChanged(app)
            if isvector(app.Signal) % checking if the user selected a signal ( error handling)
            UpdateComparisonwindow2(app);
            end
            
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            Gate;
            close(app.UIFigure);
        end

        % scaleswitch1 value changed function
        function scaleswitch1ValueChanged(app)
            value = app.scaleswitch1.Value;
            if strcmp(value, 'Linear')
            set(app.UIAxes2,'yscale','linear');
            else
            set(app.UIAxes2,'yscale','log');
            end
        end

        % window1scaleswitch value changed function
        function window1scaleswitchValueChanged(app)
            value = app.window1scaleswitch.Value;
            if strcmp(value, 'Linear')
            set(app.UIAxes4,'yscale','linear');
            else
            set(app.UIAxes4,'yscale','log');
            end
        end

        % window2scaleswitch value changed function
        function window2scaleswitchValueChanged(app)
            value = app.window2scaleswitch.Value;
            if strcmp(value, 'Linear')
            set(app.UIAxes6,'yscale','linear');
            else
            set(app.UIAxes6,'yscale','log');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Spectrum Analyzer';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Spectrum Analyzer';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = 'Input Signal';
            app.Panel.Units = 'pixels';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Audio 1', 'Audio 2', 'Rect', 'Sinc', 'Trig'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Audio 1';
            app.Panel2 = uipanel(app.Tab);
            app.Panel2.Title = 'DFT Settings';
            app.Panel2.Units = 'pixels';
            app.DropDown2 = uidropdown(app.Panel2);
            app.DropDown2.Items = {'8K-DFT', '16K-DFT', '32K-DFT', '64K-DFT'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Value = '64K-DFT';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Time Domain');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Signal');
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Frequency Domain');
            xlabel(app.UIAxes2, 'Frequency');
            ylabel(app.UIAxes2, 'Amp');
            app.NumericEditField = uieditfield(app.Tab, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @NumericEditFieldValueChanged);
            app.NumericEditField.Limits = [-100000 99999];
            app.Label = uilabel(app.Tab);
            app.Label.Text = 'Frequency Span';
            app.Label2 = uilabel(app.Tab);
            app.Label2.Text = 'Hz :';
            app.NumericEditField2 = uieditfield(app.Tab, 'numeric');
            app.NumericEditField2.ValueChangedFcn = createCallbackFcn(app, @NumericEditField2ValueChanged);
            app.NumericEditField2.Limits = [-99999 100000];
            app.Label3 = uilabel(app.Tab);
            app.Label3.Text = 'Hz';
            app.Label4 = uilabel(app.Tab);
            app.Label4.Text = 'RBW';
            app.EditField = uieditfield(app.Tab, 'text');
            app.EditField.Editable = 'off';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Back';
            app.Panel5 = uipanel(app.Tab);
            app.Panel5.Title = 'Calculations';
            app.Panel5.Units = 'pixels';
            app.Label6 = uilabel(app.Panel5);
            app.Label6.Text = 'Span Power';
            app.power = uieditfield(app.Panel5, 'text');
            app.power.Editable = 'off';
            app.Label7 = uilabel(app.Panel5);
            app.Label7.Text = 'RMS average';
            app.RMSfield = uieditfield(app.Panel5, 'text');
            app.RMSfield.Editable = 'off';
            app.Label8 = uilabel(app.Panel5);
            app.Label8.Text = 'DC Bias';
            app.DCBiasfield = uieditfield(app.Panel5, 'text');
            app.DCBiasfield.Editable = 'off';
            app.Label9 = uilabel(app.Panel5);
            app.Label9.Text = 'Peak';
            app.Peakfield = uieditfield(app.Panel5, 'text');
            app.Peakfield.Editable = 'off';
            app.Label10 = uilabel(app.Panel5);
            app.Label10.Text = 'Peak frequency';
            app.Peakfrequencyfield = uieditfield(app.Panel5, 'text');
            app.Peakfrequencyfield.Editable = 'off';
            app.Label11 = uilabel(app.Tab);
            app.Label11.Text = 'Fs';
            app.Fsfield = uieditfield(app.Tab, 'text');
            app.Fsfield.Editable = 'off';
            app.LabelToggleSwitch = uilabel(app.Tab);
            app.LabelToggleSwitch.Text = 'Scale';
            app.scaleswitch1 = uiswitch(app.Tab, 'toggle');
            app.scaleswitch1.Items = {'Log', 'Linear'};
            app.scaleswitch1.ValueChangedFcn = createCallbackFcn(app, @scaleswitch1ValueChanged);
            app.scaleswitch1.Value = 'Linear';
            app.Label14 = uilabel(app.Tab);
            app.Label14.Text = 'There are two tabs in this window:';
            app.Label15 = uilabel(app.Tab);
            app.Label15.Text = '-Spectrum Analyzer';
            app.Label16 = uilabel(app.Tab);
            app.Label16.Text = '-Comparison Mode';
            app.Label17 = uilabel(app.Tab);
            app.Label17.Text = 'Input Signal and DFT settings are for both';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Comparison Mode';
            app.Panel3 = uipanel(app.Tab2);
            app.Panel3.Title = 'Window 1';
            app.Panel3.Units = 'pixels';
            app.window1dropdown = uidropdown(app.Panel3);
            app.window1dropdown.Items = {'Rectangular', 'Triangular', 'Hanning', 'Hamming', 'No Window'};
            app.window1dropdown.ValueChangedFcn = createCallbackFcn(app, @window1dropdownValueChanged, true);
            app.window1dropdown.Value = 'No Window';
            app.LabelNumericEditField3 = uilabel(app.Panel3);
            app.LabelNumericEditField3.Enable = 'off';
            app.LabelNumericEditField3.Text = 'Length';
            app.NumericEditField3 = uieditfield(app.Panel3, 'numeric');
            app.NumericEditField3.ValueChangedFcn = createCallbackFcn(app, @NumericEditField3ValueChanged);
            app.NumericEditField3.Limits = [1 Inf];
            app.NumericEditField3.ValueDisplayFormat = '%.0f';
            app.NumericEditField3.Enable = 'off';
            app.NumericEditField3.Value = 1;
            app.UIAxes3 = uiaxes(app.Tab2);
            title(app.UIAxes3, 'Time Domain');
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Signal');
            app.UIAxes4 = uiaxes(app.Tab2);
            title(app.UIAxes4, 'Frequency Domain');
            xlabel(app.UIAxes4, 'Frequency');
            ylabel(app.UIAxes4, 'Amp');
            app.Panel4 = uipanel(app.Tab2);
            app.Panel4.Title = 'Window 2';
            app.Panel4.Units = 'pixels';
            app.Label5 = uilabel(app.Panel4);
            app.Label5.Enable = 'off';
            app.Label5.Text = 'Length';
            app.NumericEditField4 = uieditfield(app.Panel4, 'numeric');
            app.NumericEditField4.ValueChangedFcn = createCallbackFcn(app, @NumericEditField4ValueChanged);
            app.NumericEditField4.Limits = [1 Inf];
            app.NumericEditField4.ValueDisplayFormat = '%.0f';
            app.NumericEditField4.Enable = 'off';
            app.NumericEditField4.Value = 1;
            app.window2dropdown = uidropdown(app.Panel4);
            app.window2dropdown.Items = {'Rectangular', 'Triangular', 'Hanning', 'Hamming', 'No Window'};
            app.window2dropdown.ValueChangedFcn = createCallbackFcn(app, @window2dropdownValueChanged, true);
            app.window2dropdown.Value = 'No Window';
            app.UIAxes5 = uiaxes(app.Tab2);
            title(app.UIAxes5, 'Time Domain');
            xlabel(app.UIAxes5, 'X');
            ylabel(app.UIAxes5, 'Y');
            app.UIAxes6 = uiaxes(app.Tab2);
            title(app.UIAxes6, 'Frequency Domain');
            xlabel(app.UIAxes6, 'Frequency');
            ylabel(app.UIAxes6, 'Amp');
            app.Label12 = uilabel(app.Tab2);
            app.Label12.Text = 'Scale';
            app.window2scaleswitch = uiswitch(app.Tab2, 'toggle');
            app.window2scaleswitch.Items = {'Log', 'Linear'};
            app.window2scaleswitch.ValueChangedFcn = createCallbackFcn(app, @window2scaleswitchValueChanged);
            app.window2scaleswitch.Value = 'Linear';
            app.Label13 = uilabel(app.Tab2);
            app.Label13.Text = 'Scale';
            app.window1scaleswitch = uiswitch(app.Tab2, 'toggle');
            app.window1scaleswitch.Items = {'Log', 'Linear'};
            app.window1scaleswitch.ValueChangedFcn = createCallbackFcn(app, @window1scaleswitchValueChanged);
            app.window1scaleswitch.Value = 'Linear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Spectrumanalyzer()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end